classdef compression < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OutFile                   matlab.ui.control.EditField
        FileOutputEditFieldLabel  matlab.ui.control.Label
        OperateButton             matlab.ui.control.Button
        SelectaFileButton         matlab.ui.control.Button
        Algorithm                 matlab.ui.control.DropDown
        AlgorithmDropDownLabel    matlab.ui.control.Label
        Operation                 matlab.ui.control.DropDown
        OperationDropDownLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        filename = ""; % filename to select
        filepath = ""; % filepath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectaFileButton
        function SelectaFileButtonPushed(app, event)
            [app.filename, app.filepath] = uigetfile("*.txt", "select a file");
        end

        % Button pushed function: OperateButton
        function OperateButtonPushed(app, event)
            if(strcmp(app.filename, "") || strcmp(app.filepath, "") || ...
                    strcmp(app.OutFile.Value, ""))
                errordlg("You need to choose a file and specify " + ...
                    "an output file name");
            elseif(strcmp(app.Operation.Value, "compression"))
                if(strcmp(app.Algorithm.Value, "Huffman"))
                    huffman_c(app, app.OutFile.Value);
                elseif(strcmp(app.Algorithm.Value, "LZW"))
                    lzw_c(app, app.OutFile.Value);
                elseif(strcmp(app.Algorithm.Value, "RLE"))
                    rle_c(app, app.OutFile.Value);
                elseif(strcmp(app.Algorithm.Value, "SRS"))
                    simple_repetition_suppression_c(app, app.OutFile.Value);
                elseif(strcmp(app.Algorithm.Value, "Shannon-Fano"))
                    shannon_fano_c(app, app.OutFile.Value);
                elseif(strcmp(app.Algorithm.Value, "Fixed-Length"))
                    fixed_length_c(app, app.OutFile.Value);
                elseif(strcmp(app.Algorithm.Value, "Variable-Length"))
                    variable_length_c(app, app.OutFile.Value);
                end

            else
                if(strcmp(app.Algorithm.Value, "Huffman"))
                    huffman_d(app, app.OutFile.Value);
                elseif(strcmp(app.Algorithm.Value, "LZW"))
                    lzw_d(app, app.OutFile.Value);
                elseif(strcmp(app.Algorithm.Value, "RLE"))
                    rle_d(app, app.OutFile.Value);
                elseif(strcmp(app.Algorithm.Value, "SRS"))
                    simple_repetition_suppression_d(app, app.OutFile.Value);
                elseif(strcmp(app.Algorithm.Value, "Shannon-Fano"))
                    shannon_fano_d(app, app.OutFile.Value);
                elseif(strcmp(app.Algorithm.Value, "Fixed-Length"))
                    fixed_length_d(app, app.OutFile.Value);
                elseif(strcmp(app.Algorithm.Value, "Variable-Length"))
                    variable_length_d(app, app.OutFile.Value);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OperationDropDownLabel = uilabel(app.UIFigure);
            app.OperationDropDownLabel.Text = 'Operation';
            app.Operation = uidropdown(app.UIFigure);
            app.Operation.Items = {'compression', 'decompression'};
            app.Operation.Value = 'compression';
            app.AlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.Algorithm = uidropdown(app.UIFigure);
            app.Algorithm.Items = {'Huffman', 'LZW', 'RLE', 'SRS', 'Shannon-Fano', 'Fixed-Length', 'Variable-Length'};
            app.Algorithm.Value = 'Huffman';
            app.SelectaFileButton = uibutton(app.UIFigure, 'push');
            app.SelectaFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectaFileButtonPushed, true);
            app.SelectaFileButton.Text = 'Select a File';
            app.OperateButton = uibutton(app.UIFigure, 'push');
            app.OperateButton.ButtonPushedFcn = createCallbackFcn(app, @OperateButtonPushed, true);
            app.OperateButton.Text = 'Operate';
            app.FileOutputEditFieldLabel = uilabel(app.UIFigure);
            app.FileOutputEditFieldLabel.Text = 'File Output';
            app.OutFile = uieditfield(app.UIFigure, 'text');
            app.OutFile.Value = 'out';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = compression

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end